classdef Assignment_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RotationKnobLabel              matlab.ui.control.Label
        Bot_1_RotationKnob             matlab.ui.control.Knob
        xEditFieldLabel                matlab.ui.control.Label
        Bot_1_xEditField               matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        Bot_1_yEditField               matlab.ui.control.NumericEditField
        RotationKnob_2Label            matlab.ui.control.Label
        Bot_2_RotationKnob_2           matlab.ui.control.Knob
        xLabel                         matlab.ui.control.Label
        Bot_2_xEditField_2             matlab.ui.control.NumericEditField
        yEditField_2Label              matlab.ui.control.Label
        Bot_2_yEditField_2             matlab.ui.control.NumericEditField
        Robot1BaseLabel                matlab.ui.control.Label
        Robot2BaseLabel                matlab.ui.control.Label
        PartsPositionLabel             matlab.ui.control.Label
        zEditFieldLabel                matlab.ui.control.Label
        zEditField                     matlab.ui.control.NumericEditField
        zEditField_2Label              matlab.ui.control.Label
        zEditField_2                   matlab.ui.control.NumericEditField
        PCB_xLabel                     matlab.ui.control.Label
        PCB_xEditField                 matlab.ui.control.NumericEditField
        PCB_yEditFieldLabel            matlab.ui.control.Label
        PCB_yEditField                 matlab.ui.control.NumericEditField
        PCB_zEditFieldLabel            matlab.ui.control.Label
        PCB_zEditField                 matlab.ui.control.NumericEditField
        HousingTop_xEditFieldLabel     matlab.ui.control.Label
        HousingTop_xEditField          matlab.ui.control.NumericEditField
        HousingTop_yEditFieldLabel     matlab.ui.control.Label
        HousingTop_yEditField          matlab.ui.control.NumericEditField
        HousingTop_zEditFieldLabel     matlab.ui.control.Label
        HousingTop_zEditField          matlab.ui.control.NumericEditField
        HousingBottom_xEditFieldLabel  matlab.ui.control.Label
        HousingBottom_xEditField       matlab.ui.control.NumericEditField
        HousingBottom_yEditFieldLabel  matlab.ui.control.Label
        HousingBottom_yEditField       matlab.ui.control.NumericEditField
        HousingBottom_zEditFieldLabel  matlab.ui.control.Label
        HousingBottom_zEditField       matlab.ui.control.NumericEditField
        StartButton                    matlab.ui.control.Button
        DropOffPositionLabel           matlab.ui.control.Label
        xEditField_3Label              matlab.ui.control.Label
        Drop_Off_xEditField_3          matlab.ui.control.NumericEditField
        yEditField_3Label              matlab.ui.control.Label
        Drop_Off_yEditField_3          matlab.ui.control.NumericEditField
        zEditField_3Label              matlab.ui.control.Label
        Drop_Off_zEditField_3          matlab.ui.control.NumericEditField
        PlotType                       matlab.ui.container.ButtonGroup
        PlotButton                     matlab.ui.control.RadioButton
        ModelButton                    matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        startPos = [0,-pi/2,0,pi,pi/2,0];
        Tr;
    end
    

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            clf
            
            robot1 = UR3('robot1');
            robot1.SetBaseLocation(app.Bot_1_xEditField.Value,...
                app.Bot_1_yEditField.Value,0,...
                app.Bot_1_RotationKnob.Value);
            
            robot2 = UR3('robot2');
            robot2.SetBaseLocation(app.Bot_2_xEditField_2.Value,...
                app.Bot_2_yEditField_2.Value,0,...
                app.Bot_2_RotationKnob_2.Value);
            %%%%
            Table(0,0,0);
            hold all
            Cage(0,0,0);
            housingbottom_h = HousingBottom(app.HousingBottom_xEditField.Value,...
                app.HousingBottom_yEditField.Value,...
                app.HousingBottom_zEditField.Value);
            
            housingtop_h = HousingTop(app.HousingTop_xEditField.Value,...
                app.HousingTop_yEditField.Value,...
                app.HousingTop_zEditField.Value);
            
            pbc_h =  PCB(app.PCB_xEditField.Value,...
                app.PCB_yEditField.Value,...
                app.PCB_zEditField.Value);   
            %%%%
            housingtop = transl(app.HousingTop_xEditField.Value,...
                app.HousingTop_yEditField.Value,...
                app.HousingTop_zEditField.Value);
            
            housingbottom = transl(app.HousingBottom_xEditField.Value,...
                app.HousingBottom_yEditField.Value,...
                app.HousingBottom_zEditField.Value);
            
            pcb = transl(app.PCB_xEditField.Value,...
                app.PCB_yEditField.Value,...
                app.PCB_zEditField.Value);
            
            asmbly = transl(0,0,0.4);
            
            dropOff = transl(app.Drop_Off_xEditField_3.Value,...
                app.Drop_Off_yEditField_3.Value,...
                app.Drop_Off_zEditField_3.Value);
            
            bot1q1 = robot1.GetIkine(pcb,app.startPos);
            bot1q2 = robot1.GetIkine(housingbottom,bot1q1);
            bot1qAsm = robot1.GetIkine(asmbly,app.startPos);
            
            bot2q1 = robot2.GetIkine(housingtop,app.startPos);
            bot2qAsm = robot2.GetIkine(asmbly,app.startPos);
            bot2qDrop = robot2.GetIkine(dropOff,app.startPos);
            axis([-1,1,-1,1,-1,1]);
            if (app.PlotButton.Value)
                    robot1.model.plot(app.startPos)
                    hold on
                    robot2.model.plot(app.startPos)
                    axis([-1.5,1.5,-1.5,1.5,-1,1]);
                    camlight
                    %%
                    robot2.Move(app.startPos,bot2q1);
                    delete(housingtop_h)
                    robot2.Move(bot2q1,bot2qAsm);
                    housingtop_h = HousingTop(asmbly(1,4),asmbly(2,4),asmbly(3,4));
                    %%
                    robot1.Move(app.startPos,bot1q1);
                    delete(pbc_h)
                    robot1.Move(bot1q1,bot1qAsm);
                    pbc_h = PCB(asmbly(1,4),asmbly(2,4),asmbly(3,4));
                    pause(1);
                    delete(pbc_h)
                    %%
                    robot1.Move(bot1qAsm,bot1q2);
                    delete(housingbottom_h)
                    robot1.Move(bot1q2,bot1qAsm);
                    housingbottom_h = HousingBottom(asmbly(1,4),asmbly(2,4),asmbly(3,4));
                    robot1.Move(bot1qAsm,app.startPos)
                    %%
                    delete(housingbottom_h)
                    delete(housingtop_h)
                    robot2.Move(bot2qAsm,bot2qDrop);
                    housingbottom_h = HousingBottom(app.Drop_Off_xEditField_3.Value,...
                        app.Drop_Off_yEditField_3.Value,...
                        app.Drop_Off_zEditField_3.Value);
                    housingtop_h = HousingTop(app.Drop_Off_xEditField_3.Value,...
                        app.Drop_Off_yEditField_3.Value,...
                        app.Drop_Off_zEditField_3.Value);
                    robot2.Move(bot2qDrop,app.startPos);
                    
            else 
                    robot1.PlotAndColourRobot
                    robot1.model.animate(app.startPos)
                    hold on
                    robot2.PlotAndColourRobot
                    robot2.model.animate(app.startPos)
                    axis([-1.5,1.5,-1.5,1.5,-1,1]);
                    %%
                    robot2.MoveModel(app.startPos,bot2q1)
                    delete(housingtop_h)
                    robot2.MoveModel(bot2q1,bot2qAsm)
                    housingtop_h = HousingTop(asmbly(1,4),asmbly(2,4),asmbly(3,4));
                    %%
                    robot1.MoveModel(app.startPos,bot1q1)
                    
                    delete(pbc_h)
                    robot1.MoveModel(bot1q1,bot1qAsm)
                    pbc_h = PCB(asmbly(1,4),asmbly(2,4),asmbly(3,4));
                    pause(1);
                    delete(pbc_h)
                    %%
                    robot1.MoveModel(bot1qAsm,bot1q2)
                    delete(housingbottom_h)
                    robot1.MoveModel(bot1q2,bot1qAsm)
                    housingbottom_h = HousingBottom(asmbly(1,4),asmbly(2,4),asmbly(3,4));
                    robot1.MoveModel(bot1qAsm,app.startPos)
                    %%
                    delete(housingbottom_h)
                    delete(housingtop_h)
                    robot2.MoveModel(bot2qAsm,bot2qDrop)

                    housingbottom_h = HousingBottom(app.Drop_Off_xEditField_3.Value,...
                        app.Drop_Off_yEditField_3.Value,...
                        app.Drop_Off_zEditField_3.Value);
                    housingtop_h = HousingTop(app.Drop_Off_xEditField_3.Value,...
                        app.Drop_Off_yEditField_3.Value,...
                        app.Drop_Off_zEditField_3.Value);
                    
                    robot2.MoveModel(bot2qDrop,app.startPos)
            end        
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RotationKnobLabel = uilabel(app.UIFigure);
            app.RotationKnobLabel.Text = 'Rotation';
            app.Bot_1_RotationKnob = uiknob(app.UIFigure, 'continuous');
            app.Bot_1_RotationKnob.Limits = [0 360];
            app.Bot_1_RotationKnob.Value = 360;
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.Bot_1_xEditField = uieditfield(app.UIFigure, 'numeric');
            app.Bot_1_xEditField.Limits = [-1 1];
            app.Bot_1_xEditField.Value = -0.22;
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.Bot_1_yEditField = uieditfield(app.UIFigure, 'numeric');
            app.Bot_1_yEditField.Limits = [-1 1];
            app.Bot_1_yEditField.Value = 0.22;
            app.RotationKnob_2Label = uilabel(app.UIFigure);
            app.RotationKnob_2Label.Text = 'Rotation';
            app.Bot_2_RotationKnob_2 = uiknob(app.UIFigure, 'continuous');
            app.Bot_2_RotationKnob_2.Limits = [0 360];
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = {'x'; ''};
            app.Bot_2_xEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Bot_2_xEditField_2.Limits = [-1 1];
            app.Bot_2_xEditField_2.Value = 0.22;
            app.yEditField_2Label = uilabel(app.UIFigure);
            app.yEditField_2Label.Text = 'y';
            app.Bot_2_yEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Bot_2_yEditField_2.Limits = [-1 1];
            app.Bot_2_yEditField_2.Value = 0.22;
            app.Robot1BaseLabel = uilabel(app.UIFigure);
            app.Robot1BaseLabel.Text = 'Robot 1 Base';
            app.Robot2BaseLabel = uilabel(app.UIFigure);
            app.Robot2BaseLabel.Text = 'Robot 2 Base';
            app.PartsPositionLabel = uilabel(app.UIFigure);
            app.PartsPositionLabel.Text = 'Parts Position';
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'numeric');
            app.zEditField.Editable = 'off';
            app.zEditField_2Label = uilabel(app.UIFigure);
            app.zEditField_2Label.Text = 'z';
            app.zEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.zEditField_2.Editable = 'off';
            app.PCB_xLabel = uilabel(app.UIFigure);
            app.PCB_xLabel.Text = 'PCB_x';
            app.PCB_xEditField = uieditfield(app.UIFigure, 'numeric');
            app.PCB_xEditField.Limits = [-1 1];
            app.PCB_xEditField.Value = -0.3;
            app.PCB_yEditFieldLabel = uilabel(app.UIFigure);
            app.PCB_yEditFieldLabel.Text = 'PCB_y';
            app.PCB_yEditField = uieditfield(app.UIFigure, 'numeric');
            app.PCB_yEditField.Limits = [-1 1];
            app.PCB_yEditField.Value = 0.5;
            app.PCB_zEditFieldLabel = uilabel(app.UIFigure);
            app.PCB_zEditFieldLabel.Text = 'PCB_z';
            app.PCB_zEditField = uieditfield(app.UIFigure, 'numeric');
            app.PCB_zEditField.Limits = [-1 1];
            app.PCB_zEditField.Editable = 'off';
            app.HousingTop_xEditFieldLabel = uilabel(app.UIFigure);
            app.HousingTop_xEditFieldLabel.Text = 'HousingTop_x';
            app.HousingTop_xEditField = uieditfield(app.UIFigure, 'numeric');
            app.HousingTop_xEditField.Limits = [-1 1];
            app.HousingTop_xEditField.Value = 0.42;
            app.HousingTop_yEditFieldLabel = uilabel(app.UIFigure);
            app.HousingTop_yEditFieldLabel.Text = 'HousingTop_y';
            app.HousingTop_yEditField = uieditfield(app.UIFigure, 'numeric');
            app.HousingTop_yEditField.Limits = [-1 1];
            app.HousingTop_yEditField.Value = 0.47;
            app.HousingTop_zEditFieldLabel = uilabel(app.UIFigure);
            app.HousingTop_zEditFieldLabel.Text = 'HousingTop_z';
            app.HousingTop_zEditField = uieditfield(app.UIFigure, 'numeric');
            app.HousingTop_zEditField.Editable = 'off';
            app.HousingBottom_xEditFieldLabel = uilabel(app.UIFigure);
            app.HousingBottom_xEditFieldLabel.Text = 'HousingBottom_x';
            app.HousingBottom_xEditField = uieditfield(app.UIFigure, 'numeric');
            app.HousingBottom_xEditField.Value = -0.42;
            app.HousingBottom_yEditFieldLabel = uilabel(app.UIFigure);
            app.HousingBottom_yEditFieldLabel.Text = 'HousingBottom_y';
            app.HousingBottom_yEditField = uieditfield(app.UIFigure, 'numeric');
            app.HousingBottom_yEditField.Limits = [-1 1];
            app.HousingBottom_yEditField.Value = 0.47;
            app.HousingBottom_zEditFieldLabel = uilabel(app.UIFigure);
            app.HousingBottom_zEditFieldLabel.Text = 'HousingBottom_z';
            app.HousingBottom_zEditField = uieditfield(app.UIFigure, 'numeric');
            app.HousingBottom_zEditField.Editable = 'off';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DropOffPositionLabel = uilabel(app.UIFigure);
            app.DropOffPositionLabel.Text = 'Drop Off Position';
            app.xEditField_3Label = uilabel(app.UIFigure);
            app.xEditField_3Label.Text = 'x';
            app.Drop_Off_xEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Drop_Off_xEditField_3.Limits = [-1 1];
            app.Drop_Off_xEditField_3.Value = 0.6;
            app.yEditField_3Label = uilabel(app.UIFigure);
            app.yEditField_3Label.Text = 'y';
            app.Drop_Off_yEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Drop_Off_yEditField_3.Limits = [-1 1];
            app.Drop_Off_yEditField_3.Value = 0.4;
            app.zEditField_3Label = uilabel(app.UIFigure);
            app.zEditField_3Label.Text = 'z';
            app.Drop_Off_zEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.PlotType = uibuttongroup(app.UIFigure);
            app.PlotType.Title = 'Type';
            app.PlotButton = uiradiobutton(app.PlotType);
            app.PlotButton.Text = 'Plot';
            app.ModelButton = uiradiobutton(app.PlotType);
            app.ModelButton.Text = 'Model';
            app.ModelButton.Value = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Assignment_1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end