classdef assignment_2_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        CleanTab                    matlab.ui.container.Tab
        DescriptionTextArea_6Label  matlab.ui.control.Label
        DescriptionTextArea_6       matlab.ui.control.TextArea
        SetClean                    matlab.ui.control.Button
        AutoMoveTab                 matlab.ui.container.Tab
        DescriptionTextArea_2Label  matlab.ui.control.Label
        DescriptionTextArea_2       matlab.ui.control.TextArea
        XEditFieldLabel             matlab.ui.control.Label
        XEditField                  matlab.ui.control.NumericEditField
        YEditFieldLabel             matlab.ui.control.Label
        YEditField                  matlab.ui.control.NumericEditField
        ZEditFieldLabel             matlab.ui.control.Label
        ZEditField                  matlab.ui.control.NumericEditField
        DesiredEndEffectorPositionLabel  matlab.ui.control.Label
        SetAuto                     matlab.ui.control.Button
        ManualMoveTab               matlab.ui.container.Tab
        SetManual                   matlab.ui.control.Button
        DescriptionTextArea_7Label  matlab.ui.control.Label
        DescriptionTextArea_7       matlab.ui.control.TextArea
        GoHomeButton                matlab.ui.control.Button
        CameraCalibrationTab        matlab.ui.container.Tab
        CalibrateScaleButton        matlab.ui.control.Button
        CalibratePlaneButton        matlab.ui.control.Button
        CalibrateBasePointButton    matlab.ui.control.Button
        XEditField_2Label           matlab.ui.control.Label
        XEditField_2                matlab.ui.control.NumericEditField
        YEditField_2Label           matlab.ui.control.Label
        YEditField_2                matlab.ui.control.NumericEditField
        ZEditField_2Label           matlab.ui.control.Label
        ZEditField_2                matlab.ui.control.NumericEditField
        RollEditFieldLabel          matlab.ui.control.Label
        RollEditField               matlab.ui.control.NumericEditField
        PitchEditFieldLabel         matlab.ui.control.Label
        PitchEditField              matlab.ui.control.NumericEditField
        YawEditFieldLabel           matlab.ui.control.Label
        YawEditField                matlab.ui.control.NumericEditField
        ThresholdLabel              matlab.ui.control.Label
        CameraThreshold             matlab.ui.control.NumericEditField
        BlobSizeEditFieldLabel      matlab.ui.control.Label
        CameraBlob                  matlab.ui.control.NumericEditField
        CameraValueLabel            matlab.ui.control.Label
        EStop                       matlab.ui.control.Button
        Start                       matlab.ui.control.Button
        EndEffectorPositionLabel_2  matlab.ui.control.Label
        CurrentModeEditFieldLabel   matlab.ui.control.Label
        CurrentMode                 matlab.ui.control.EditField
        CameraSet                   matlab.ui.control.Button
        CameraDataLabel             matlab.ui.control.Label
        CameraData                  matlab.ui.control.EditField
        CameraReset                 matlab.ui.control.Button
        GetImageButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        mode;       % Current mode
        threshold;  % Camera threshold
        blob;       % Camera blob
        cleanerHandle;  % Handle to the autoclean class
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetClean
        function SetCleanPushed(app, event)
            app.mode = 1;
            app.CurrentMode.Value = 'Clean';
        end

        % Button pushed function: SetAuto
        function SetAutoPushed(app, event)
            app.mode = 2;
            app.CurrentMode.Value = 'Auto Move';
        end

        % Button pushed function: SetManual
        function SetManualButtonPushed(app, event)
            app.mode = 3;
            app.CurrentMode.Value = 'Manual';
        end

        % Button pushed function: Start
        function StartButtonPushed(app, event)
            startButton(app);
        end

        % Button pushed function: CameraSet
        function CameraSetButtonPushed(app, event)
            app.threshold = app.CameraThreshold.Value;
            app.blob = app.CameraBlob.Value;
            app.CameraData.Value = 'Set';
        end

        % Button pushed function: CameraReset
        function CameraResetButtonPushed(app, event)
            app.CameraThreshold.Value = 0.2;
            app.CameraBlob.Value = 100;
            app.threshold = app.CameraThreshold.Value;
            app.blob = app.CameraBlob.Value;
            app.CameraData.Value = 'Default';
        end

        % Button pushed function: CalibrateScaleButton
        function CalibrateScaleButtonPushed(app, event)
            app.cleanerHandle.CalibrateScale();
        end

        % Button pushed function: CalibratePlaneButton
        function CalibratePlaneButtonPushed(app, event)
            app.cleanerHandle.CalibratePlane();
        end

        % Button pushed function: GetImageButton
        function GetImageButtonPushed(app, event)
            app.cleanerHandle.processImage();
        end

        % Button pushed function: GoHomeButton
        function GoHomeButtonPushed(app, event)
            app.cleanerHandle.robot.realRobotHAL.homeRobot();
        end

        % Button pushed function: CalibrateBasePointButton
        function CalibrateBasePointButtonPushed(app, event)
            app.cleanerHandle.CalibrateBasePoint();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CleanTab = uitab(app.TabGroup);
            app.CleanTab.Title = 'Clean';
            app.DescriptionTextArea_6Label = uilabel(app.CleanTab);
            app.DescriptionTextArea_6Label.Text = 'Description';
            app.DescriptionTextArea_6 = uitextarea(app.CleanTab);
            app.DescriptionTextArea_6.Value = {'The robot will detect,  move toward the desired position and perform cleaning action without requiring further user input.'};
            app.SetClean = uibutton(app.CleanTab, 'push');
            app.SetClean.ButtonPushedFcn = createCallbackFcn(app, @SetCleanPushed, true);
            app.SetClean.Text = 'Set Clean';
            app.AutoMoveTab = uitab(app.TabGroup);
            app.AutoMoveTab.Title = 'AutoMove';
            app.DescriptionTextArea_2Label = uilabel(app.AutoMoveTab);
            app.DescriptionTextArea_2Label.Text = 'Description';
            app.DescriptionTextArea_2 = uitextarea(app.AutoMoveTab);
            app.DescriptionTextArea_2.Editable = 'off';
            app.DescriptionTextArea_2.Value = {'Assign the desired cordinate and tell the robot to move toward that position.'};
            app.XEditFieldLabel = uilabel(app.AutoMoveTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.AutoMoveTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.AutoMoveTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.AutoMoveTab, 'numeric');
            app.ZEditFieldLabel = uilabel(app.AutoMoveTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.AutoMoveTab, 'numeric');
            app.DesiredEndEffectorPositionLabel = uilabel(app.AutoMoveTab);
            app.DesiredEndEffectorPositionLabel.Text = 'Desired End Effector Position';
            app.SetAuto = uibutton(app.AutoMoveTab, 'push');
            app.SetAuto.ButtonPushedFcn = createCallbackFcn(app, @SetAutoPushed, true);
            app.SetAuto.Text = 'Set AutoMove';
            app.ManualMoveTab = uitab(app.TabGroup);
            app.ManualMoveTab.Title = 'ManualMove';
            app.SetManual = uibutton(app.ManualMoveTab, 'push');
            app.SetManual.ButtonPushedFcn = createCallbackFcn(app, @SetManualButtonPushed, true);
            app.SetManual.Text = 'Set Manual';
            app.DescriptionTextArea_7Label = uilabel(app.ManualMoveTab);
            app.DescriptionTextArea_7Label.Text = 'Description';
            app.DescriptionTextArea_7 = uitextarea(app.ManualMoveTab);
            app.DescriptionTextArea_7.Value = {'Manually cntrol the robot using a controller '};
            app.GoHomeButton = uibutton(app.ManualMoveTab, 'push');
            app.GoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @GoHomeButtonPushed, true);
            app.GoHomeButton.Text = 'Go Home';
            app.CameraCalibrationTab = uitab(app.TabGroup);
            app.CameraCalibrationTab.Title = 'CameraCalibration';
            app.CalibrateScaleButton = uibutton(app.CameraCalibrationTab, 'push');
            app.CalibrateScaleButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateScaleButtonPushed, true);
            app.CalibrateScaleButton.Text = 'Calibrate Scale';
            app.CalibratePlaneButton = uibutton(app.CameraCalibrationTab, 'push');
            app.CalibratePlaneButton.ButtonPushedFcn = createCallbackFcn(app, @CalibratePlaneButtonPushed, true);
            app.CalibratePlaneButton.Text = 'CalibratePlane';
            app.CalibrateBasePointButton = uibutton(app.CameraCalibrationTab, 'push');
            app.CalibrateBasePointButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateBasePointButtonPushed, true);
            app.CalibrateBasePointButton.Text = 'Calibrate Base Point';
            app.XEditField_2Label = uilabel(app.UIFigure);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_2.Editable = 'off';
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_2.Editable = 'off';
            app.ZEditField_2Label = uilabel(app.UIFigure);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_2.Editable = 'off';
            app.RollEditFieldLabel = uilabel(app.UIFigure);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.UIFigure, 'numeric');
            app.RollEditField.Editable = 'off';
            app.PitchEditFieldLabel = uilabel(app.UIFigure);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditField.Editable = 'off';
            app.YawEditFieldLabel = uilabel(app.UIFigure);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.UIFigure, 'numeric');
            app.YawEditField.Editable = 'off';
            app.ThresholdLabel = uilabel(app.UIFigure);
            app.ThresholdLabel.Text = 'Threshold';
            app.CameraThreshold = uieditfield(app.UIFigure, 'numeric');
            app.CameraThreshold.Limits = [0 0.3];
            app.CameraThreshold.Value = 0.2;
            app.BlobSizeEditFieldLabel = uilabel(app.UIFigure);
            app.BlobSizeEditFieldLabel.Text = 'Blob Size';
            app.CameraBlob = uieditfield(app.UIFigure, 'numeric');
            app.CameraBlob.Limits = [0 Inf];
            app.CameraBlob.Value = 100;
            app.CameraValueLabel = uilabel(app.UIFigure);
            app.CameraValueLabel.Text = 'Camera Value';
            app.EStop = uibutton(app.UIFigure, 'push');
            app.EStop.Icon = 'Stop Button.jpg';
            app.EStop.Text = '';
            app.Start = uibutton(app.UIFigure, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.Start.Icon = 'Start.jpg';
            app.Start.Text = '';
            app.EndEffectorPositionLabel_2 = uilabel(app.UIFigure);
            app.EndEffectorPositionLabel_2.Text = 'End Effector Position';
            app.CurrentModeEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentModeEditFieldLabel.Text = 'Current Mode';
            app.CurrentMode = uieditfield(app.UIFigure, 'text');
            app.CurrentMode.Editable = 'off';
            app.CameraSet = uibutton(app.UIFigure, 'push');
            app.CameraSet.ButtonPushedFcn = createCallbackFcn(app, @CameraSetButtonPushed, true);
            app.CameraSet.Text = 'Set';
            app.CameraDataLabel = uilabel(app.UIFigure);
            app.CameraDataLabel.Text = 'Camera Data';
            app.CameraData = uieditfield(app.UIFigure, 'text');
            app.CameraData.Editable = 'off';
            app.CameraData.Value = 'Default';
            app.CameraReset = uibutton(app.UIFigure, 'push');
            app.CameraReset.ButtonPushedFcn = createCallbackFcn(app, @CameraResetButtonPushed, true);
            app.CameraReset.Text = 'Reset';
            app.GetImageButton = uibutton(app.UIFigure, 'push');
            app.GetImageButton.ButtonPushedFcn = createCallbackFcn(app, @GetImageButtonPushed, true);
            app.GetImageButton.Text = 'Get Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = assignment_2_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end